classdef CE_Experiment_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        IntroPanel        matlab.ui.container.Panel
        StartButton       matlab.ui.control.Button
        SubjectNameField  matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        SubjectIDField    matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        MainPanel         matlab.ui.container.Panel
        NextButton        matlab.ui.control.Button
        CEEditField       matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        QuestionLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        QuestionsFull       % 全データ（+ヘッダー）
        QuestionsShuffled   % ランダム並びの出題
        ResponseData        % 回答付きのcell配列
        CurrentIndex = 1
    end
    
    methods (Access = private)
        
        function updateQuestion(app)
            idx = app.CurrentIndex + 1;
            total = size(app.ResponseData, 1) - 1;
            if idx > size(app.ResponseData, 1)
                app.QuestionLabel.Text = '全問終了。保存しています...';
                pause(1);

                id = app.SubjectIDField.Value;
                filename_base = sprintf('CE_result_%s_%s', id, datestr(now,'yyyymmdd_HHMMSS'));

                % Excel 保存
                writetable(cell2table(app.ResponseData(2:end,:), ...
                    'VariableNames', app.ResponseData(1,:)), [filename_base '.xlsx']);

                % 構造体変換と保存
                data = app.ResponseData(2:end,:);
                for i = 1:size(data,1)
                    r(i).type = data{i,1};
                    r(i).p1 = data{i,2}; r(i).x1 = data{i,3};
                    r(i).p2 = data{i,4}; r(i).x2 = data{i,5};
                    r(i).p3 = data{i,6}; r(i).x3 = data{i,7};
                    r(i).CE = data{i,8};
                end
                resultData.subject_id = id;
                resultData.subject_name = app.SubjectNameField.Value;
                resultData.timestamp = datetime('now');
                resultData.responses = r;
                save([filename_base '.mat'], 'resultData');

                app.QuestionLabel.Text = sprintf('ありがとうございました。\n%s.xlsx / .mat\n5秒後に終了します。', filename_base);
                pause(5);
                delete(app);
                return;
            end

            row = app.ResponseData(idx,:);
            qtype = row{1};
            if strcmp(qtype, 'binary')
                detail = sprintf('%.0f%%の確率で¥%.0f、%.0f%%の確率で¥%.0f', ...
                    row{2}*100, row{3}, row{4}*100, row{5});
            else
                detail = sprintf('%.0f%%の確率で¥%.0f、%.0f%%の確率で¥%.0f、%.0f%%の確率で¥%.0f', ...
                    row{2}*100, row{3}, row{4}*100, row{5}, row{6}*100, row{7});
            end
        
            app.QuestionLabel.Text = sprintf('(%d/%d)\nこのギャンブルのCE（確実性等価）を入力してください：\n%s', ...
                app.CurrentIndex, total, detail);
            app.CEEditField.Value = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初期UI状態
            app.MainPanel.Visible = 'off';
            app.IntroPanel.Visible = 'on';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % 課題読み込みと初期化
            app.QuestionsFull = readcell('CE_task_GonzalezWuStyle.xlsx');
            header = app.QuestionsFull(1,:);
            data = app.QuestionsFull(2:end,:);
            rng('shuffle');
            idx = randperm(size(data,1));
            app.QuestionsShuffled = data(idx,:);
            app.ResponseData = [header; app.QuestionsShuffled];

            % UI切り替え
            app.IntroPanel.Visible = 'off';
            app.MainPanel.Visible = 'on';
            app.updateQuestion();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            idx = app.CurrentIndex + 1;
                val = app.CEEditField.Value;
            
                if ~isnumeric(val) || ~isscalar(val) || isnan(val)
                    uialert(app.UIFigure, 'CE（確実性等価）を数値で入力してください。', '入力エラー');
                    return;
                end
            
                if idx <= size(app.ResponseData, 1)
                    app.ResponseData{idx, 8} = val;
                    app.CurrentIndex = app.CurrentIndex + 1;
                    app.updateQuestion();
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainPanel = uipanel(app.UIFigure);
            app.QuestionLabel = uilabel(app.MainPanel);
            app.QuestionLabel.Text = '';
            app.Label_3 = uilabel(app.MainPanel);
            app.Label_3.Text = '確実性等価：';
            app.CEEditField = uieditfield(app.MainPanel, 'numeric');
            app.CEEditField.Limits = [0 Inf];
            app.CEEditField.AllowEmpty = 'on';
            app.NextButton = uibutton(app.MainPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '次の問題へ行く';
            app.IntroPanel = uipanel(app.UIFigure);
            app.Label = uilabel(app.IntroPanel);
            app.Label.Text = '学籍番号';
            app.SubjectIDField = uieditfield(app.IntroPanel, 'text');
            app.Label_2 = uilabel(app.IntroPanel);
            app.Label_2.Text = '氏名';
            app.SubjectNameField = uieditfield(app.IntroPanel, 'text');
            app.StartButton = uibutton(app.IntroPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '実験を始める';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CE_Experiment_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end