classdef CE_Experiment_App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel_Choice         matlab.ui.container.Panel
        Button_Choice2       matlab.ui.control.Button
        Button_Choice1       matlab.ui.control.Button
        Label_Choice2        matlab.ui.control.Label
        Label_Choice1        matlab.ui.control.Label
        Label_Progress       matlab.ui.control.Label
        Panel_Intro          matlab.ui.container.Panel
        Button_Start         matlab.ui.control.Button
        EditField_Name       matlab.ui.control.EditField
        Label_2              matlab.ui.control.Label
        EditField_StudentID  matlab.ui.control.EditField
        Label                matlab.ui.control.Label
    end

    
    properties (Access = private)
        trialData     table      % 問題データ（60行）
        responses     table      % 回答記録 [trial, p1...y2, choice]
        currentTrial  double     % 現在の問題番号
    end
    
    methods (Access = private)
        
        function updateTrialDisplay(app)
            trial = app.trialData(app.currentTrial, :);
            type = trial.type;
        
            if type == 1  % one
                p1 = trial.p1; x1 = trial.x1;
                q1 = trial.q1; y1 = trial.y1;
        
                if abs(round(q1*1000) - round(q1*100)*10) ~= 0
                    app.Label_Choice1.Text = sprintf("選択肢1:\np=%.3f, x=%.0f", p1, x1);
                    app.Label_Choice2.Text = sprintf("選択肢2:\np=%.3f, x=%.0f", q1, y1);
                else
                    app.Label_Choice1.Text = sprintf("選択肢1:\np=%.2f, x=%.0f", p1, x1);
                    app.Label_Choice2.Text = sprintf("選択肢2:\np=%.2f, x=%.0f", q1, y1);
                end
        
            elseif type == 2  % two
                p1 = trial.p1; x1 = trial.x1;
                p2 = trial.p2; x2 = trial.x2;
                q1 = trial.q1; y1 = trial.y1;
                q2 = trial.q2; y2 = trial.y2;
        
                if abs(round(q1*1000) - round(q1*100)*10) ~= 0
                    app.Label_Choice1.Text = sprintf("選択肢1:\np1=%.2f, x1=%.0f;\n p2=%.2f, x2=%.0f", p1, x1, p2, x2);
                    app.Label_Choice2.Text = sprintf("選択肢2:\np1=%.3f, x1=%.0f;\n p2=%.2f, x2=%.0f", q1, y1, q2, y2);
                else                    
                    app.Label_Choice1.Text = sprintf("選択肢1:\np1=%.2f, x1=%.0f;\n p2=%.2f, x2=%.0f", p1, x1, p2, x2);
                    app.Label_Choice2.Text = sprintf("選択肢2:\np1=%.2f, x1=%.0f;\n p2=%.2f, x2=%.0f", q1, y1, q2, y2);
                end
            end
            app.Button_Choice1.Text = "選択肢1をえらぶ";
            app.Button_Choice2.Text = "選択肢2をえらぶ";
        
            app.Label_Progress.Text = sprintf("問題 %d / %d", app.currentTrial, height(app.trialData));
        end

        function recordChoice(app, choice)
            trial = app.currentTrial;
            row = app.trialData(trial, :);  % 1行ぶんの table
        
            % 回答を記録
            app.responses.choice(trial)   = choice;
            app.responses.p1(trial)       = row.p1;
            app.responses.q1(trial)       = row.q1;
            app.responses.x1(trial)       = row.x1;
            app.responses.y1(trial)       = row.y1;
            app.responses.p2(trial)       = row.p2;
            app.responses.q2(trial)       = row.q2;
            app.responses.x2(trial)       = row.x2;
            app.responses.y2(trial)       = row.y2;
            app.responses.typecode(trial) = row.type;  % 1 or 2
        
            app.currentTrial = trial + 1;
        
            if app.currentTrial > height(app.trialData)
                sid = app.EditField_StudentID.Value;
                filename = sprintf("AnsCE_%s.xlsx", sid);
        
                % ① 自分の現在のフォルダに保存
                writetable(app.responses, filename);
                
                % ② 自分のフォルダ名を取得（例: "Exp0707" など）
                currentFolder = pwd;
                [~, folderName] = fileparts(currentFolder);
                
                % ③ Matlab DriveのパスをOSごとに取得
                if ispc
                    driveRoot = fullfile(getenv('USERPROFILE'), 'Matlab Drive');
                else
                    driveRoot = fullfile(getenv('HOME'), 'Matlab Drive');
                end
                
                % ④ 同名の共有フォルダ（Matlab Drive直下）を仮定
                sharedFolder = fullfile(driveRoot, folderName);
                
                % ⑤ 共有フォルダが存在すればそこにも保存
                if isfolder(sharedFolder)
                    sharedPath = fullfile(sharedFolder, filename);
                    writetable(app.responses, sharedPath);
                    fprintf('"%s" を共有フォルダ "%s" に保存しました。\n', filename, sharedFolder);
                else
                    warning('共有フォルダ "%s" が見つかりませんでした。保存は自分のフォルダのみです。', sharedFolder);
                end
        
                % アラートと終了
                uialert(app.UIFigure, "すべての問題が終了しました！3秒後に終了します。", "完了");
                pause(3);
                delete(app);  % アプリ終了
            else
                updateTrialDisplay(app);
            end
        end
        
        
        function folder = findSharedFolder(app,folderNames)
            % 現在の作業フォルダから上位階層をたどって共有フォルダを探す
            folder = "";
            current = pwd;
        
            for i = 1:5  % 最大5階層まで上がって探す
                parent = fileparts(current);
                for j = 1:length(folderNames)
                    candidate = fullfile(parent, folderNames{j});
                    if isfolder(candidate)
                        folder = candidate;
                        return;
                    end
                end
                current = parent;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初期状態では選択肢パネルを非表示
            app.Panel_Choice.Visible = false;
            app.UIFigure.Visible = true;
        end

        % Button pushed function: Button_Start
        function Button_StartPushed(app, event)
             all = readtable("all_attribute_data.xlsx", 'Sheet', 'all_combined');

            % ★ すべての列をdoubleに変換（必要な列のみでもOK）★
            numericVars = {'p1','q1','x1','y1','p2','q2','x2','y2','type'};
            for i = 1:length(numericVars)
                if iscell(all.(numericVars{i}))
                    all.(numericVars{i}) = cellfun(@str2double, all.(numericVars{i}));
                elseif iscategorical(all.(numericVars{i}))
                    all.(numericVars{i}) = double(all.(numericVars{i}));
                end
            end
        
            rng('shuffle');
            rand_indices = randperm(height(all), 60);
        
            app.trialData = all(rand_indices, :);
            app.responses = table('Size', [60, 10], ...
                'VariableTypes', repmat("double", 1, 10), ...
                'VariableNames', {'choice','p1','q1','x1','y1','p2','q2','x2','y2','typecode'});
            app.currentTrial = 1;
        
            app.Panel_Intro.Visible = false;
            app.Panel_Choice.Visible = true;
        
            updateTrialDisplay(app);
        end

        % Button pushed function: Button_Choice2
        function Button_Choice2Pushed(app, event)
            recordChoice(app, 2);
        end

        % Button pushed function: Button_Choice1
        function Button_Choice1Pushed(app, event)
            recordChoice(app, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_Intro = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel_Intro);
            app.Label.Text = '学籍番号';
            app.EditField_StudentID = uieditfield(app.Panel_Intro, 'text');
            app.Label_2 = uilabel(app.Panel_Intro);
            app.Label_2.Text = '氏　名　';
            app.EditField_Name = uieditfield(app.Panel_Intro, 'text');
            app.Button_Start = uibutton(app.Panel_Intro, 'push');
            app.Button_Start.ButtonPushedFcn = createCallbackFcn(app, @Button_StartPushed, true);
            app.Button_Start.Text = '実験を始める';
            app.Panel_Choice = uipanel(app.UIFigure);
            app.Label_Progress = uilabel(app.Panel_Choice);
            app.Label_Progress.Text = '';
            app.Label_Choice1 = uilabel(app.Panel_Choice);
            app.Label_Choice1.Text = '選択肢２をえらぶ';
            app.Label_Choice2 = uilabel(app.Panel_Choice);
            app.Label_Choice2.Text = '選択肢１をえらぶ';
            app.Button_Choice1 = uibutton(app.Panel_Choice, 'push');
            app.Button_Choice1.ButtonPushedFcn = createCallbackFcn(app, @Button_Choice1Pushed, true);
            app.Button_Choice1.Text = '';
            app.Button_Choice2 = uibutton(app.Panel_Choice, 'push');
            app.Button_Choice2.ButtonPushedFcn = createCallbackFcn(app, @Button_Choice2Pushed, true);
            app.Button_Choice2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CE_Experiment_App2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end